classdef gausian_beam_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        BeamParametersLabel    matlab.ui.control.Label
        LengthmLabel           matlab.ui.control.Label
        BeamwaistmLabel        matlab.ui.control.Label
        WavelengthnmLabel      matlab.ui.control.Label
        slider_z               matlab.ui.control.Slider
        LambdanmEditField      matlab.ui.control.NumericEditField
        W0mEditField           matlab.ui.control.NumericEditField
        BeamSummaryTable       matlab.ui.control.Table
        Tab2                   matlab.ui.container.Tab
        SnowCheckBox           matlab.ui.control.CheckBox
        RainCheckBox           matlab.ui.control.CheckBox
        FoggCheckBox           matlab.ui.control.CheckBox
        visibilityLabel        matlab.ui.control.Label
        slider_visibility      matlab.ui.control.Slider
        BeamParametersLabel_2  matlab.ui.control.Label
        BeamSummaryTable_2     matlab.ui.control.Table
        RightPanel             matlab.ui.container.Panel
        intensity_trans_plot   matlab.ui.control.UIAxes
        intensity_rad_plot     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        lambda = 1000e-9; 
        w0 = 1e-3;
        z = 0;
        Tsignal = 1;
        visibility = 4;
        intensity_attenuation = 0;
        intensity_rad_attenuation = 0;
    end
    
    
    methods (Access = private)
        
         function plot_gb_rad(app)
            [~,~,app.intensity_attenuation,app.intensity_rad_attenuation] = calc_attenuation(app.z,app.visibility,app.lambda,app.w0);
            [x,y,I] = calc_intensity(app.z,app.lambda,app.w0);

            %if app.intensity_rad_attenuation == 0
            %    imagesc(app.intensity_rad_plot,x,y,I);
            %else
            imagesc(app.intensity_rad_plot,x,y,app.intensity_rad_attenuation);
            %end

            %imagesc(app.intensity_rad_plot, x, y, I);
            axis(app.intensity_rad_plot, 'equal');
            axis(app.intensity_rad_plot, 'tight');
            xlabel(app.intensity_rad_plot, 'x (m)');
            ylabel(app.intensity_rad_plot, 'y (m)');
            title(app.intensity_rad_plot, ['Gaussian Beam Intensity at z = ', num2str(app.z), ' m']);
            cb = colorbar(app.intensity_rad_plot);
            cb.Label.String = 'Intensity (W/m^2)';   % <-- Add your label here
            colormap(app.intensity_rad_plot, 'jet')
         end

         function plot_gb_trans(app)
            [~,~,app.intensity_attenuation,app.intensity_rad_attenuation] = calc_attenuation(app.z,app.visibility,app.lambda,app.w0);
            [~,z_,y,I] = calc_intensity_transvers(app.lambda,app.w0);
            
            %if app.intensity_attenuation == 0
            %    imagesc(app.intensity_trans_plot, z_, y, I');
            %else
            imagesc(app.intensity_trans_plot, z_, y, app.intensity_attenuation');
            %end

            axis(app.intensity_trans_plot,'xy'); % so z increases to right and y increases upwards
            %axis(app.intensity_trans_plot, 'equal');
            axis(app.intensity_trans_plot, 'tight');
            xlabel(app.intensity_trans_plot,'z (m)');
            ylabel(app.intensity_trans_plot,'y (m)');
            title(app.intensity_trans_plot,'Gaussian beam intensity (y-z plane)');
            colorbar(app.intensity_trans_plot);
            colormap(app.intensity_trans_plot,'jet');
            cb = colorbar(app.intensity_trans_plot);
            cb.Label.String = 'Intensity (W/m^2)';   % <-- Add your label here
            colormap(app.intensity_trans_plot, 'jet')
        end
        
        function updateBeamSummaryTable(app, targettable,targetlabel)
            zR = pi*app.w0^2 / app.lambda;
            theta = app.lambda / (pi*app.w0);
            w = app.w0 * sqrt(1 + (app.z/zR)^2);
        
            summaryData = {
                'Beam waist w0', sprintf('%.3f mm', app.w0*1e3);
                'Rayleigh range zR', sprintf('%.2f cm', zR*1e2);
                'Divergence θ', sprintf('%.2f deg', rad2deg(theta));
                'Current beam radius', sprintf('%.3f mm', w*1e3);
            };
            
            targettable.ColumnName = {};   % No headers
            targettable.Data = summaryData;
            
            targetlabel.FontWeight = 'Bold';
            targetlabel.FontSize = 15;
            targetlabel.HorizontalAlignment = 'center';
            
        end

       %function plot_visibility_update(app)
       %     [~,~,app.intensity_attenuation,app.intensity_rad_attenuation] = calc_attenuation(app.z,app.visibility,app.lambda,app.w0);

       %end

        function TitleLabelsEdit(~,Label,text)
            Label.FontSize = 15;
            Label.FontWeight = 'bold';
            Label.HorizontalAlignment = 'center';
            Label.Text = text;

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.plot_gb_rad();
            app.plot_gb_trans();
            
            app.updateBeamSummaryTable(app.BeamSummaryTable);
            app.updateBeamSummaryTable(app.BeamSummaryTable_2)
            app.TitleLabelsEdit(app.BeamParametersLabel, 'Beam Parameters')
            app.TitleLabelsEdit(app.BeamParametersLabel_2, 'Atmospheric Parameters')
            
       
        end

        % Value changing function: slider_z
        function slider_zValueChanging2(app, event)
            changingValue = event.Value;
            app.z = changingValue;
            app.plot_gb_rad();
            app.plot_gb_trans();
        end

        % Value changed function: LambdanmEditField
        function LambdanmEditFieldValueChanged(app, event)
            value = app.LambdanmEditField.Value;
            app.lambda = value * 1e-9;
            app.plot_gb_rad();
            app.plot_gb_trans();
        end

        % Value changed function: W0mEditField
        function W0mEditFieldValueChanged(app, event)
            value = app.W0mEditField.Value;
            app.w0 = value;
            app.plot_gb_rad();
            app.plot_gb_trans();
        end

        % Callback function
        function ButtonPushed(app, event)
           app.TabGroup.SelectedTab = app.Tab2;
        end

        % Value changing function: slider_visibility
        function slider_visibilityValueChanging(app, event)
            app.visibility = event.Value;
            %app.plot_visibility_update();
            app.plot_gb_rad();
            app.plot_gb_trans();
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {250, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.BeamSummaryTable = uitable(app.Tab);
            app.BeamSummaryTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.BeamSummaryTable.RowName = {};
            app.W0mEditField = uieditfield(app.Tab, 'numeric');
            app.W0mEditField.ValueChangedFcn = createCallbackFcn(app, @W0mEditFieldValueChanged, true);
            app.LambdanmEditField = uieditfield(app.Tab, 'numeric');
            app.LambdanmEditField.ValueChangedFcn = createCallbackFcn(app, @LambdanmEditFieldValueChanged, true);
            app.slider_z = uislider(app.Tab);
            app.slider_z.ValueChangingFcn = createCallbackFcn(app, @slider_zValueChanging2, true);
            app.slider_z.Step = 2;
            app.WavelengthnmLabel = uilabel(app.Tab);
            app.WavelengthnmLabel.Text = 'Wavelength (nm)';
            app.BeamwaistmLabel = uilabel(app.Tab);
            app.BeamwaistmLabel.Text = 'Beam waist (m)';
            app.LengthmLabel = uilabel(app.Tab);
            app.LengthmLabel.Text = 'Length (m)';
            app.BeamParametersLabel = uilabel(app.Tab);
            app.BeamParametersLabel.Text = 'Beam Parameters';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.BeamSummaryTable_2 = uitable(app.Tab2);
            app.BeamSummaryTable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.BeamSummaryTable_2.RowName = {};
            app.BeamParametersLabel_2 = uilabel(app.Tab2);
            app.BeamParametersLabel_2.Text = 'Beam Parameters';
            app.slider_visibility = uislider(app.Tab2);
            app.slider_visibility.Limits = [0 4];
            app.slider_visibility.MajorTicks = [0 1 2 3 4];
            app.slider_visibility.MajorTickLabels = {'0', '1', '6', '50', '50+'};
            app.slider_visibility.ValueChangingFcn = createCallbackFcn(app, @slider_visibilityValueChanging, true);
            app.slider_visibility.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4];
            app.slider_visibility.Step = 1;
            app.visibilityLabel = uilabel(app.Tab2);
            app.visibilityLabel.Text = 'Visibility';
            app.FoggCheckBox = uicheckbox(app.Tab2);
            app.FoggCheckBox.Text = 'Fogg';
            app.RainCheckBox = uicheckbox(app.Tab2);
            app.RainCheckBox.Text = 'Rain';
            app.SnowCheckBox = uicheckbox(app.Tab2);
            app.SnowCheckBox.Text = 'Snow';
            app.RightPanel = uipanel(app.GridLayout);
            app.intensity_rad_plot = uiaxes(app.RightPanel);
            title(app.intensity_rad_plot, 'Title')
            xlabel(app.intensity_rad_plot, 'X')
            ylabel(app.intensity_rad_plot, 'Y')
            zlabel(app.intensity_rad_plot, 'Z')
            app.intensity_trans_plot = uiaxes(app.RightPanel);
            title(app.intensity_trans_plot, 'Title')
            xlabel(app.intensity_trans_plot, 'X')
            ylabel(app.intensity_trans_plot, 'Y')
            zlabel(app.intensity_trans_plot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gausian_beam_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end